classdef RiboLabRV2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        file_menu              matlab.ui.container.Menu
        load_pdb_file          matlab.ui.container.Menu
        Untitled_4             matlab.ui.container.Menu
        Untitled_3             matlab.ui.container.Menu
        Export_results         matlab.ui.container.Menu
        Untitled_2             matlab.ui.container.Menu
        uipanel1               matlab.ui.container.Panel
        Helix_File             matlab.ui.control.EditField
        text6                  matlab.ui.control.Label
        Domain_File            matlab.ui.control.EditField
        text5                  matlab.ui.control.Label
        StructureFile          matlab.ui.control.EditField
        Structre_File          matlab.ui.control.Label
        PDB_FileList           matlab.ui.control.EditField
        text4                  matlab.ui.control.Label
        FullFR3DFile           matlab.ui.control.EditField
        text8                  matlab.ui.control.Label
        HelixColorFile         matlab.ui.control.EditField
        text13                 matlab.ui.control.Label
        DomainColorFile        matlab.ui.control.EditField
        text14                 matlab.ui.control.Label
        AlignmentFile          matlab.ui.control.EditField
        text15                 matlab.ui.control.Label
        uipanel3               matlab.ui.container.Panel
        DomainDefbox           matlab.ui.control.CheckBox
        helixDefBox            matlab.ui.control.CheckBox
        meanBFactBox           matlab.ui.control.CheckBox
        ProteinContactsBox     matlab.ui.control.CheckBox
        EntropyBox             matlab.ui.control.CheckBox
        MagContactsBox         matlab.ui.control.CheckBox
        ProteinInteractionBox  matlab.ui.control.CheckBox
        FR3D_InteractionsBox   matlab.ui.control.CheckBox
        MagInteractionBox      matlab.ui.control.CheckBox
        text16                 matlab.ui.control.Label
        CoVarEntropyBox        matlab.ui.control.CheckBox
        CalculateBtn           matlab.ui.control.Button
        SaveBtn                matlab.ui.control.Button
        uipanel4               matlab.ui.container.Panel
        MoleculeNamesTable     matlab.ui.control.Table
        uipanel5               matlab.ui.container.Panel
        text1                  matlab.ui.control.Label
        DataSetName            matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function RiboLabRV2_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to RiboLabRV2 (see VARARGIN)
%             
%             % Choose default command line output for RiboLabRV2
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: CalculateBtn
        function CalculateBtn_Callback(app, event)
            % hObject    handle to CalculateBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h=PleaseWait();
            OnionShellWidth_Callback(hObject, eventdata, handles);
            OnionCenterTextBox_Callback(hObject, eventdata, handles);
            
            result=get(app.output,'UserData');
            RiboLabCads=result.RiboLabCads;
            CadsTable=get(app.MoleculeNamesTable,'Data');
            if ~isempty(result) && isfield(result,'OnionFiles')
                OnionFiles=result.OnionFiles;
            end
            RiboLabCads_rRNA=[];
            RiboLabCads_rRNA_Names={};
            RiboLabCads_rProtein_Names={};
            RiboLabCads_rProtein=[];
            rRNA_Chains=[];
            rProtein_Chains=[];
            ProteinMenu=[];
            
            numCads=length(RiboLabCads);
            for i=1:numCads
                if strcmp(CadsTable(i,3),'rRNA')
                    RiboLabCads_rRNA=[RiboLabCads_rRNA,RiboLabCads(i)];
                    RiboLabCads_rRNA_Names=[RiboLabCads_rRNA_Names,CadsTable{i,4}];
                    rRNA_Chains=[rRNA_Chains,';',CadsTable{i,5}];
                elseif strcmp(CadsTable(i,3),'rProtein')
                    RiboLabCads_rProtein=[RiboLabCads_rProtein,RiboLabCads(i)];
                    RiboLabCads_rProtein_Names=[RiboLabCads_rProtein_Names,CadsTable{i,4}];
                    rProtein_Chains=[rProtein_Chains,';',CadsTable{i,5}];
                    ProteinMenu=[ProteinMenu;{[CadsTable{i,3},':',CadsTable{i,4},':',CadsTable{i,5}]}];
                end
                MoleculeChainMap.(['mol_',CadsTable{i,4}])=CadsTable{i,5};
                ChainMoleculeMap.(['chain_',CadsTable{i,5}])=CadsTable{i,4};
                MoleculeGroupMap.(['mol_',CadsTable{i,4}])=CadsTable{i,2};
            end
            result.rRNA_Chains=rRNA_Chains;
            result.rProtein_Chains=rProtein_Chains;
            result.MoleculeChainMap=MoleculeChainMap;
            result.ChainMoleculeMap=ChainMoleculeMap;
            result.MoleculeGroupMap=MoleculeGroupMap;
            result.RiboLabCads_rProtein_Names=RiboLabCads_rProtein_Names;
            result.RiboLabCads_rRNA_Names=RiboLabCads_rRNA_Names;
            result.ProteinMenu=ProteinMenu;
            
            %%
                 RiboLabMap = Map2D(get(app.DataSetName,'String'));
                 ResidueList = RiboLabMap.AddMapStructureFile(result.StructureFile,MoleculeChainMap);
                 %RiboLabMap.TransformAuto();
                 result.RiboLabMap=RiboLabMap;
                 result.ResidueList=ResidueList;
            %%
            
            if get(app.meanBFactBox,'value')
                Thermal_Factor_Table = ThermalFactorTable(RiboLabCads_rRNA,...
                    repmat({RiboLabMap.ItemNames},1,length(RiboLabCads_rRNA)));
                numDataPoints=size(Thermal_Factor_Table,1);
                for j = 1: numDataPoints
                    chainID=regexp(Thermal_Factor_Table{j,1},'([^_]+)_','tokens');
                    Thermal_Factor_Table{j,1}=regexprep(Thermal_Factor_Table{j,1},'([^_]+)_',[ChainMoleculeMap.(['chain_',chainID{1}{1}]),':']);
                    if isempty(Thermal_Factor_Table{j,2})
                        Thermal_Factor_Table{j,2}='';
                    else
                         Thermal_Factor_Table{j,2}=num2str(Thermal_Factor_Table{j,2});
                    end
                end
                 result.Thermal_Factor_Table=Thermal_Factor_Table;
            end
            
            if get(app.fineOnionBox,'value') || get(app.coarseOnionBox,'value')
                for i=1:length(RiboLabCads_rRNA)
                    OnionFiles.Onions(i) = Onion([RiboLabCads_rRNA(i).Name,' Onion'],RiboLabCads_rRNA(i).PDB,OnionFiles.Center,OnionFiles.Layers);
                end
                RiboLabCads_rRNA_Names=regexprep(RiboLabCads_rRNA_Names,'S','S:');
                if get(app.fineOnionBox,'value')
                    result.Fine_Onion_Table=OnionFiles.Onions.OnionTable(RiboLabMap.ItemNames,'ModelType','fine','PreFix',RiboLabCads_rRNA_Names);
                end
                if get(app.coarseOnionBox,'value')
                    result.Coarse_Onion_Table=OnionFiles.Onions.OnionTable(RiboLabMap.ItemNames,'ModelType','coarse','PreFix',RiboLabCads_rRNA_Names);
                end
            end
            if get(app.ProteinOnionBox,'value')
                ProteinItemNames={};
                for i=1:length(RiboLabCads_rProtein)
                    OnionFiles.ProteinOnions(i) = Onion([RiboLabCads_rProtein(i).Name,' Onion'],RiboLabCads_rProtein(i).PDB,OnionFiles.Center,OnionFiles.Layers);
                    ProteinItemNames=vertcat(ProteinItemNames,unique(OnionFiles.ProteinOnions(i).Structure.UniqueResSeq));
                end
                ProteinItemNames=regexprep(ProteinItemNames,'_[\s]*','');
                Protein_Prefixes=regexprep(RiboLabCads_rProtein_Names,'(.*)','$1:');
                result.Protein_Onion_Table=OnionFiles.ProteinOnions.OnionTable(ProteinItemNames,'ModelType','fine','PreFix',Protein_Prefixes);
            end
            if get(app.helixDefBox,'value')
                FakeMap.ItemNames=ResidueList;
                Helix_Table=HelixDefinitions(result.HelixDef,FakeMap,'DisplayPlots',false,'ColorFile',result.HelixColorFile);
                result.Helix_Table=Helix_Table;
            end
            if get(app.DomainDefbox,'value')
                FakeMap.ItemNames=ResidueList;
                Domain_Table=DomainDefinitions(result.DomainDef,FakeMap,'DisplayPlots',false,'ColorFile',result.DomainColorFile);
                result.Domain_Table=Domain_Table;
            end
            if get(app.FR3D_InteractionsBox,'value')
                for i=1:length(result.RiboLabFullBP)
                    result.RiboLabFullBP(i).Name=['rRNA ',num2str(i)];
                end
                [~,FilteredBP]=PlotBPbyType(result.RiboLabFullBP,RiboLabMap,'DisplayPlots',false);
                %BP2Table([FilteredBP{:}],'TableFormat','website','ItemList',RiboLabMap.ItemNames);
                BPorg=BPorganize([FilteredBP{:}]);
                BPorg(5)=[]; % Remove other type
                DeDupped_BPs=DeDupBP(BPorg);
                FR3D_Interaction_Tables=BP2Table(DeDupped_BPs,'TableFormat','website2','ItemList',RiboLabMap.ItemNames,'ItemList2',ResidueList,'Merge',false);
                result.FR3D_Interaction_Tables=FR3D_Interaction_Tables;
            end
            if get(app.EntropyBox,'value') || get(app.CoVarEntropyBox,'value')
                A=fastaread(result.AlignmentFile);
                RiboLabCads_rRNA(1).AddAlignment('premade',A);
                FindSpecies=regexprep(lower(RiboLabCads_rRNA(1).Species),'[\s_]','');
                LookSpecies=lower(regexprep({A.Header},'[\s_]',''));
                [~,Species_Ind]=ismember(FindSpecies,LookSpecies);
                B=RiboLabCads_rRNA(1).PlotAlignment('species',Species_Ind,'ShowPlots',false);
                RiboLabCads_rRNA(1).Alignment=B{1};
            
                H=Seq_entropy(RiboLabCads_rRNA(1).Alignment,'Gap_mode','prorate');
                numSamples = length(H{1});
                ConservationTable=cell(numSamples+1,9);
                ConservationTable{1,1}='resNum';
                ConservationTable(2:end,1) = result.ResidueList(1:length(H{1}));
                ConservationTable{1,2}='resName';
                ConservationTable(2:end,2)=cellstr(RiboLabCads_rRNA(1).Alignment(Species_Ind).Sequence');
                ConservationTable{1,3} = 'Consensus';
                ConservationTable(2:end,3)= cellstr(regexprep(seqconsensus(RiboLabCads_rRNA(1).Alignment,...
                    'Alphabet','NT','Gaps','all'),'T','U')');
                ConservationTable(1,4:8) = {'A','C','G','U','Gaps'};
                ConservationTable(2:end,4:8) = num2cell(seqprofile(RiboLabCads_rRNA(1).Alignment,...
                    'Alphabet','NT','Gaps','all')');
                ConservationTable{1,9} = 'Shannon';
                ConservationTable(2:end,9) = num2cell(H{1});
            
                result.Conservation_Table=ConservationTable;
            
                if get(app.EntropyBox,'value')
                    result.Entropy_Table=[result.ResidueList(1:length(H{1})),num2cell(H{1}')];
                    result.Entropy_Table=vertcat(result.Entropy_Table, ...
                        [result.ResidueList(length(H{1})+1:end),repmat({'\N'},...
                        length(result.ResidueList(length(H{1})+1:end)),1)]);
                end
            
                if get(app.CoVarEntropyBox,'value')
                    %Assume FR3D was calculated, because checkbox rules says it has to
                    %have been.
                    H_CoVar=CoVarEntropy(RiboLabCads_rRNA(1).Alignment,FR3D_Interaction_Tables,{'cww'},'ByTypes');
                    result.CoVarEntropy_Table=[result.ResidueList(1:length(H_CoVar{1})),num2cell(H_CoVar{1}')];
                    result.CoVarEntropy_Table=vertcat(result.CoVarEntropy_Table, ...
                        [result.ResidueList(length(H_CoVar{1})+1:end),repmat({'\N'},...
                        length(result.ResidueList(length(H_CoVar{1})+1:end)),1)]);
                end
            
            end
            
            if get(app.ProteinContactsBox,'value') || get(app.MagContactsBox,'value')
                %FakeMap.ItemNames=ResidueList;
                %Domain_Table=DomainDefinitions(result.DomainDef,FakeMap,'DisplayPlots',false,'ColorFile',result.DomainColorFile);
                NewTarget.Name=[RiboLabCads_rRNA.Name];
                NewTarget.ID=RiboLabCads_rRNA(1).PDB.ID;
                NewTarget.PDB=PDBentry();
                newpdb=RiboLabCads_rRNA(1).PDB.PDB;
                for i=2:length(RiboLabCads_rRNA)
                    newpdb.Model.Atom=[newpdb.Model.Atom,RiboLabCads_rRNA(i).PDB.PDB.Model.Atom];
                end
                NewTarget.PDB.PDBfromStruct(newpdb);
                NewTarget.PDB.Process();
                RiboLabCads.AddTarget(NewTarget.PDB);%Because they are handles, adding Target to main Cads object, also adds it to the RiboLabCads_rProtein and RiboLabCads_rRNA objects.
            
                rvfam=FullAtomModel();
                rvfam.PopulateFAM(NewTarget.PDB.PDB);
                chains=unique(cellstr(vertcat(NewTarget.PDB.PDB.Model.Atom.chainID)));
                x=Chain('',chains);
                x.CreateChain(rvfam,chains);
                RVResidues=x.residues;
                [RVResidues,RealAtomsNames_B]=AtomFilter(RVResidues,'rna');
            
                if get(app.ProteinContactsBox,'value')
                    for j=1:length(RiboLabCads_rProtein)
                        RiboLabCads_rProtein(j).Settings.Structure.ContactCutoff=3.4;
                        RiboLabCads_rProtein(j).FilterMap('AtomFilterType',{'protein','rna'},'ResidueTarget',{RVResidues},'RealAtomsNames_B',RealAtomsNames_B);
                        %disp(j);
                    end
                     Protein_Contact_Table=ProteinContactTable(RiboLabCads_rProtein,RiboLabMap);
                     result.Protein_Contact_Table=Protein_Contact_Table{1};
                end
                if get(app.ProteinInteractionBox,'value')
                    RiboLab_BP_NPN = ContactMap2BP(RiboLabCads_rProtein,'MapMode','withinTarget','bp_type','NPN');
                    Protein_Interaction_Table=BP2Table(RiboLab_BP_NPN,'TableFormat','website2','ItemList',RiboLabMap.ItemNames,'ProteinCol',RiboLabCads_rProtein_Names);
                    result.Protein_Interaction_Table=Protein_Interaction_Table;
                end
                if get(app.MagContactsBox,'value')
                    cutoffs={'6.0','2.6','2.4'};%make the one you want interactions for last.
                    numCutoff=length(cutoffs);
                    Mg_Individual_Contact_Table=cell(1,numCutoff);
                    MgColNames=cell(1,numCutoff);
                    for k=1:numCutoff
                        MgColNames{k}=['Mg_ions_', regexprep(cutoffs{k},'[^\d]','')];
                        for m=1:length(RiboLabCads)
                            RiboLabCads(m).Settings.Structure.ContactCutoff=str2double(cutoffs{k});
                            %check through all just in case right now
                            RiboLabCads(m).FilterMap('AtomFilterType',{'magnesium','rna'},'Subsets',[],'ResidueTarget',{RVResidues},'RealAtomsNames_B',RealAtomsNames_B);
                            %disp([k,m]);
                        end
                        Mg_Individual_Contact_Table{k}=ProteinContactTable(RiboLabCads,RiboLabMap,'TableFormat','website2','Magnesium',true,'CombineCols',true);
                    end
                    result.Mg_Individual_Contact_Table=Mg_Individual_Contact_Table(end:-1:1);
                    result.MgColNames=MgColNames(end:-1:1);
                end
            
                if get(app.MagInteractionBox,'value')
                    RiboLab_BP_NMN = ContactMap2BP(RiboLabCads,'MapMode','withinTarget','bp_type','NMN');
                    Magnesium_Interaction_Table=BP2Table(RiboLab_BP_NMN,'TableFormat','website2','ItemList',RiboLabMap.ItemNames);
                    result.Magnesium_Interaction_Table=Magnesium_Interaction_Table;
                end
            end
            
            result.RiboLabCads_rRNA=RiboLabCads_rRNA;
            result.RiboLabCads_rProtein=RiboLabCads_rProtein;
            result.OnionFiles=OnionFiles;
            set(app.output,'UserData',result);
            delete(h);
        end

        % Value changed function: CoVarEntropyBox
        function CoVarEntropyBox_Callback(app, event)
            % hObject    handle to CoVarEntropyBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of CoVarEntropyBox
            if get(hObject,'Value')
                set(app.FR3D_InteractionsBox,'Value',1)
            end
        end

        % Menu selected function: Export_results
        function Export_results_Callback(app, event)
            % hObject    handle to Export_results (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            results=get(app.output,'UserData');
            assignin('base',get(app.DataSetName,'String'),results);
        end

        % Value changed function: FR3D_InteractionsBox
        function FR3D_InteractionsBox_Callback(app, event)
            % hObject    handle to FR3D_InteractionsBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of FR3D_InteractionsBox
            if ~get(hObject,'Value')
                set(app.CoVarEntropyBox,'Value',0)
            end
        end

        % Value changed function: MagContactsBox
        function MagContactsBox_Callback(app, event)
            % hObject    handle to MagContactsBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of MagContactsBox
            if ~get(hObject,'Value')
                set(app.MagInteractionBox,'Value',0)
            end
        end

        % Value changed function: MagInteractionBox
        function MagInteractionBox_Callback(app, event)
            % hObject    handle to MagInteractionBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of MagInteractionBox
            
            if get(hObject,'Value')
                set(app.MagContactsBox,'Value',1)
            end
        end

        % Callback function
        function OnionCenterButtons_SelectionChangeFcn(app, event)
            % hObject    handle to the selected object in OnionCenterButtons
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            drawnow();
            result=get(app.output,'UserData');
            if ~isempty(result) && isfield(result,'OnionFiles')
                OnionFiles=result.OnionFiles;
            end
            
            
            switch get(eventdata.NewValue,'Tag') % Get Tag of selected object.
                case 'supPTC'
                    OnionFiles.Center=[-32.3240 123.0920 161.9990];
                case 'supDCC'
                   OnionFiles.Center= [-60.9250 89.2770 91.1510];
                case 'customXYZ'
                   OnionFiles.Center= str2num(char(regexp(get(app.OnionCenterTextBox,'String'),',','split')))';
                case 'customAtomN'
                    UniqueAtomName = get(app.OnionCenterTextBox,'String');
                    % Convert Syntax later as appropiate.
                    RiboLabPDBs=result.RiboLabPDBs;
                    numPDBs=length(RiboLabPDBs);
                    I=0;j=0;
                    while ~I && j < numPDBs
                        j=j+1;
                        [~,I]=ismember(UniqueAtomName,RiboLabPDBs(j).UniqueAtomNames);
                    end
                    if I == 0 && ~isempty(UniqueAtomName)
                        error('Atom not found');
                    elseif I > 0
                        OnionFiles.Center = [RiboLabPDBs(j).PDB.Model.Atom(I).X, RiboLabPDBs(j).PDB.Model.Atom(I).Y, RiboLabPDBs(j).PDB.Model.Atom(I).Z];
                    else
                        return;
                    end
                    % Continue with more cases as necessary.
                otherwise
                   error('Huh?');
            end
            result.OnionFiles=OnionFiles;
            set(app.output,'UserData',result);
        end

        % Callback function
        function OnionCenterTextBox_Callback(app, event)
            % hObject    handle to OnionCenterTextBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of OnionCenterTextBox as text
            %        str2double(get(hObject,'String')) returns contents of OnionCenterTextBox as a double
            result=get(app.output,'UserData');
            if ~isempty(result) && isfield(result,'OnionFiles')
                OnionFiles=result.OnionFiles;
            end
            
            if get(app.customXYZ,'Value')
                OnionFiles.Center= str2num(char(regexp(get(app.OnionCenterTextBox,'String'),',','split')))';
            elseif get(app.customAtomN,'Value')
                UniqueAtomName = get(app.OnionCenterTextBox,'String');
                % Convert Syntax later as appropiate.
                RiboLabPDBs=result.RiboLabPDBs;
                numPDBs=length(RiboLabPDBs);
                I=0;j=0;
                while ~I && j < numPDBs
                    j=j+1;
                    [~,I]=ismember(UniqueAtomName,RiboLabPDBs(j).UniqueAtomNames);
                end
                if I == 0 && ~isempty(UniqueAtomName)
                    error('Atom not found');
                elseif I > 0
                    OnionFiles.Center = [RiboLabPDBs(j).PDB.Model.Atom(I).X, RiboLabPDBs(j).PDB.Model.Atom(I).Y, RiboLabPDBs(j).PDB.Model.Atom(I).Z];
                end
            elseif get(app.supPTC,'Value')
                % New center based on cif file, 4V9D
                OnionFiles.Center=[-79.501 -50.248 4.312];
            elseif get(app.supDCC,'Value')
                % No new DCC defined yet. Using this would lead to incorrect results.
                OnionFiles.Center= [-60.9250 89.2770 91.1510];
            end
            result.OnionFiles=OnionFiles;
            set(app.output,'UserData',result);
        end

        % Callback function
        function OnionShellWidth_Callback(app, event)
            % hObject    handle to OnionShellWidth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of OnionShellWidth as text
            %        str2double(get(hObject,'String')) returns contents of OnionShellWidth as a double
            result=get(app.output,'UserData');
            if ~isempty(result) && isfield(result,'OnionFiles')
                OnionFiles=result.OnionFiles;
            end
            
            OnionFiles.Layers=str2double(get(app.OnionShellWidth,'String'));
            
            result.OnionFiles=OnionFiles;
            set(app.output,'UserData',result);
        end

        % Value changed function: ProteinContactsBox
        function ProteinContactsBox_Callback(app, event)
            % hObject    handle to ProteinContactsBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ProteinContactsBox
            if ~get(hObject,'Value')
                set(app.ProteinInteractionBox,'Value',0)
            end
        end

        % Value changed function: ProteinInteractionBox
        function ProteinInteractionBox_Callback(app, event)
            % hObject    handle to ProteinInteractionBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ProteinInteractionBox
            if get(hObject,'Value')
                set(app.ProteinContactsBox,'Value',1)
            end
        end

        % Button pushed function: SaveBtn
        function SaveBtn_Callback(app, event)
            % hObject    handle to SaveBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            result=get(app.output,'UserData');
            RiboLabMap=result.RiboLabMap;
            %RiboLabCads_rRNA= result.RiboLabCads_rRNA;
            %result.RiboLabCads_rProtein_Names
            %result.RiboLabCads_rRNA_Names
            %RiboLabCads_rProtein = result.RiboLabCads_rProtein;
            %ResidueList=result.ResidueList;
            %R=[RiboLabCads_rRNA.PDB];
            Table = RiboLabMap.BasicTable(result.ResidueList);
            split_name=regexp(get(app.DataSetName,'String'),'_','split');
            if length(split_name) < 2
                split_name{2}='';
            end
            Table=[Table,repmat(split_name,length(Table),1)];
            [DataSetName_path]=fileparts(result.File);
            writetable(cell2table(Table,'VariableNames',{'map_Index','molName','resNum','unModeResName','modResName','X','Y','Species_Abr','AltForm'}), [DataSetName_path,...
                '\',get(app.DataSetName,'String'),'_SecondaryStructures','.csv'] );
            
            
            TableHeader={};
            Table_SD2={};
            if get(app.DomainDefbox,'value')
                Table_SD2=[Table_SD2,result.Domain_Table(:,2:4)];
                %TableHeader=[TableHeader,{'Domain_RN','Domain_AN','Domains_Color'}];
            end
            if get(app.helixDefBox,'value')
                Table_SD2=[Table_SD2,result.Helix_Table(:,2:3)];
                %TableHeader=[TableHeader,{'Helix_Num','Helix_Color'}];
            end
            if ~isempty(Table_SD2)
                writetable(cell2table(Table_SD2), [DataSetName_path,...
                '\',get(app.DataSetName,'String'),'_StructuralData2','.csv']);
            end
            
            Table_SD3={};
            if get(app.fineOnionBox,'value')
                Table_SD3=[Table_SD3,result.Fine_Onion_Table(:,2)];
                TableHeader=[TableHeader,{'FineOnion'}];
            end
            if get(app.coarseOnionBox,'value')
                Table_SD3=[Table_SD3,result.Coarse_Onion_Table(:,2)];
                TableHeader=[TableHeader,{'Onion'}];
            end
            if get(app.meanBFactBox,'value')
                Table_SD3=[Table_SD3,result.Thermal_Factor_Table(:,2)];
                TableHeader=[TableHeader,{'mean_tempFactor'}];
            end
            
            if get(app.EntropyBox,'value')
                Table_SD3=[Table_SD3,result.Entropy_Table(:,2)];
                TableHeader=[TableHeader,{'Shannon_Entropy'}];
            end
            if get(app.CoVarEntropyBox,'value')
                Table_SD3=[Table_SD3,result.CoVarEntropy_Table(:,2)];
                TableHeader=[TableHeader,{'CoVar_Entropy'}];
            end
            if get(app.ProteinContactsBox,'value')
                %Table_SD3=[Table_SD3,result.Protein_Contact_Table(:,2:end)];
                %TableHeader=[TableHeader,[result.RiboLabCads_rProtein_Names]];
            end
            if get(app.MagContactsBox,'value')
               % d=[result.Mg_Individual_Contact_Table{:}];
                %numMgCols=length(result.Mg_Individual_Contact_Table);
               % e=[d{:}];
               % Table_SD3=[Table_SD3,e(:,2:2:2*numMgCols)];
               % TableHeader=[TableHeader,[result.MgColNames]];
            end
            
            
            if ~isempty(Table_SD3)
                writetable(cell2table(Table_SD3,'VariableNames',TableHeader(1,:)), [DataSetName_path,...
                '\',get(app.DataSetName,'String'),'_StructuralData3','.csv'] );
            end
            
            % xlswrite([DataSetName_path,'\',get(app.DataSetName,'String'),'.xlsx'],vertcat(TableHeader,Table));
            if get(app.ProteinInteractionBox,'value')
                NPN={'resIndex1','resIndex2','bp_type','bp_group','ProteinResName','ProteinResNo','StructureName'};
                writetable(cell2table([result.Protein_Interaction_Table,cellstr(repmat(result.RiboLabPDBs(1).ID,...
                    length(result.Protein_Interaction_Table),1))],'VariableNames',NPN),...
                    [DataSetName_path,'\',get(app.DataSetName,'String'),'_NPN','.csv'] );
            %     xlswrite([DataSetName_path,'\',get(app.DataSetName,'String'),'_NPN','.xlsx'],NPN);
            
            end
            if get(app.MagInteractionBox,'value')
                NMN={'resIndex1','resIndex2','bp_type','bp_group','StructureName'};
                writetable(cell2table([result.Magnesium_Interaction_Table,cellstr(repmat(result.RiboLabPDBs(1).ID,...
                    length(result.Magnesium_Interaction_Table),1))],'VariableNames',NMN), [DataSetName_path,...
                    '\',get(app.DataSetName,'String'),'_NMN','.csv'] );
            %     xlswrite([DataSetName_path,'\',get(app.DataSetName,'String'),'_NMN','.xlsx'],NMN);
            
            end
            if get(app.FR3D_InteractionsBox,'value')
                NN={'residue_i','residue_j','bp_type','bp_group','StructureName'};
                %for i=1:length(Names)
                writetable(cell2table([result.FR3D_Interaction_Tables,cellstr(repmat(result.RiboLabPDBs(1).ID,...
                    length(result.FR3D_Interaction_Tables),1))],'VariableNames',NN), [DataSetName_path,...
                    '\',get(app.DataSetName,'String'),'_FR3D','.csv'] );
                %         xlswrite([DataSetName_path,'\',get(app.DataSetName,'String'),'_',Names{i},'.xlsx'],...
               % end
            end
            if get(app.ProteinOnionBox,'value')
                 writetable(cell2table(result.Protein_Onion_Table,...
                        'VariableNames',{'resNum','DataCol'}), [DataSetName_path,...
                    '\',get(app.DataSetName,'String'),'_ProteinOnions','.csv'] );
            
            %     xlswrite([DataSetName_path,'\',get(app.DataSetName,'String'),'_ProteinOnions','.xlsx'],...
            %         [{'resNum','DataCol'};result.Protein_Onion_Table]);
            end
            if get(app.EntropyBox,'value')
                writetable(cell2table(result.Conservation_Table(2:end,:),...
                    'VariableNames',result.Conservation_Table(1,:)), [DataSetName_path,...
                    '\',get(app.DataSetName,'String'),'_ConservationTable','.csv'] );
                %     xlswrite([DataSetName_path,'\',get(app.DataSetName,'String'),'_ConservationTable','.xlsx'],...
            %         [result.Conservation_Table(1,:);{'varchar(11)','varchar(3)','char(1)',...
            %         'real(5,4)','real(5,4)','real(5,4)','real(5,4)','real(5,4)','real(5,4)'};result.Conservation_Table(2:end,:)]);
            end
            
            %%Species Table Load CSV
            SpeciesTable=struct();
            spn=result.RiboLabCads_rRNA(1).Species{1};
            sn=regexp(spn,'([^\s]+)\s(.+)','tokens');
            
            SpeciesTable.Species_Name=[upper(sn{1}{1}(1)),lower(sn{1}{1}(2:end)),' ',...
                lower(sn{1}{2})];
            
            dsn=get(app.DataSetName,'String');
            if strfind(dsn,'_') > 0
                dsn2=regexp(dsn,'([^_]+)_(.+)','tokens');
                SpeciesTable.Species_Abr=dsn2{1}{1};
                SpeciesTable.Subunit=dsn2{1}{2};
            else
                SpeciesTable.Species_Abr=dsn;
                %SpeciesTable.Subunit='other';
            
            end
            
            %SpeciesTable.Species_Name=spn;
            %SpeciesTable.DataSetName='<b>3D-based Structure</b> (recommended)';
            %SpeciesTable.MapType='3D-based';
            %SpeciesTable.Orientation='portrait';
            SpeciesTable.SS_Table=[dsn,'_LSU'];
            SpeciesTable.Font_Size_SVG=round(RiboLabMap.FontSize,1);
            % Magic correction factor for canvas font size. Reason unknown.
            SpeciesTable.Font_Size_Canvas=round(0.8*RiboLabMap.FontSize,1);
            % Magic number to get reasonably sized circles.
            SpeciesTable.Circle_Radius=round(.55*RiboLabMap.FontSize,1);
            
            writetable(struct2table(SpeciesTable), [DataSetName_path,...
                '\',get(app.DataSetName,'String'),'_SpeciesTable','.csv'] );
            
            % Chain List
            numRNAs = length(result.RiboLabCads_rRNA_Names);
            ChainList=[cellstr(repmat(result.RiboLabPDBs(1).ID,numRNAs,1)),...
                result.RiboLabCads_rRNA_Names',regexp(result.rRNA_Chains(2:end),';','split')'];
            % After RNA, do protein
            numProts = length(result.RiboLabCads_rProtein_Names);
            ChainList=[ChainList; cellstr(repmat(result.RiboLabPDBs(1).ID,numProts,1)),...
               result.RiboLabCads_rProtein_Names',regexp(result.rProtein_Chains(2:end),';','split')'];
            
            writetable(cell2table(ChainList), [DataSetName_path,...
                '\',get(app.DataSetName,'String'),'_ChainList','.csv'] );
        end

        % Value changing function: PDB_FileList
        function PDB_FileListValueChanging(app, event)
            changingValue = event.Value;
            alert()
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'RiboLabRV2';
            app.file_menu = uimenu(app.figure1);
            app.file_menu.Text = 'File';
            app.load_pdb_file = uimenu(app.file_menu);
            app.load_pdb_file.Enable = 'off';
            app.load_pdb_file.Accelerator = 'O';
            app.load_pdb_file.Text = 'Load PDB File';
            app.Untitled_4 = uimenu(app.figure1);
            app.Untitled_4.Text = 'Settings';
            app.Untitled_3 = uimenu(app.figure1);
            app.Untitled_3.Text = 'MATLAB';
            app.Export_results = uimenu(app.Untitled_3);
            app.Export_results.MenuSelectedFcn = createCallbackFcn(app, @Export_results_Callback, true);
            app.Export_results.Text = 'Export Results';
            app.Untitled_2 = uimenu(app.figure1);
            app.Untitled_2.Text = 'Help';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Loaded Files';
            app.Helix_File = uieditfield(app.uipanel1, 'text');
            app.Helix_File.Value = 'Click Here to Load:';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Text = 'Helix Definition File:';
            app.Domain_File = uieditfield(app.uipanel1, 'text');
            app.Domain_File.Value = 'Click Here to Load:';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'Domain Definition File:';
            app.StructureFile = uieditfield(app.uipanel1, 'text');
            app.StructureFile.Value = 'Click Here to Load:';
            app.Structre_File = uilabel(app.uipanel1);
            app.Structre_File.Text = '2nd_Structure Definition File:';
            app.PDB_FileList = uieditfield(app.uipanel1, 'text');
            app.PDB_FileList.ValueChangingFcn = createCallbackFcn(app, @PDB_FileListValueChanging, true);
            app.PDB_FileList.Value = 'Click Here to Load:';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'mmCIF File:';
            app.FullFR3DFile = uieditfield(app.uipanel1, 'text');
            app.FullFR3DFile.Value = 'Click Here to Load:';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Text = 'FR3D File:';
            app.HelixColorFile = uieditfield(app.uipanel1, 'text');
            app.HelixColorFile.Value = 'Click Here to Load:';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Text = 'Helix Color File:';
            app.DomainColorFile = uieditfield(app.uipanel1, 'text');
            app.DomainColorFile.Value = 'Click Here to Load:';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = 'Domain Color File:';
            app.AlignmentFile = uieditfield(app.uipanel1, 'text');
            app.AlignmentFile.Value = 'Click Here to Load:';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Text = 'Alignment File:';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Calculate';
            app.DomainDefbox = uicheckbox(app.uipanel3);
            app.DomainDefbox.Text = 'Domain Definitons';
            app.DomainDefbox.Value = true;
            app.helixDefBox = uicheckbox(app.uipanel3);
            app.helixDefBox.Text = 'Helix Definitions';
            app.helixDefBox.Value = true;
            app.meanBFactBox = uicheckbox(app.uipanel3);
            app.meanBFactBox.Text = 'mean B Factors';
            app.meanBFactBox.Value = true;
            app.ProteinContactsBox = uicheckbox(app.uipanel3);
            app.ProteinContactsBox.ValueChangedFcn = createCallbackFcn(app, @ProteinContactsBox_Callback, true);
            app.ProteinContactsBox.Text = 'Protein Contacts';
            app.ProteinContactsBox.Value = true;
            app.EntropyBox = uicheckbox(app.uipanel3);
            app.EntropyBox.Text = 'Shannon Entropy';
            app.EntropyBox.Value = true;
            app.MagContactsBox = uicheckbox(app.uipanel3);
            app.MagContactsBox.ValueChangedFcn = createCallbackFcn(app, @MagContactsBox_Callback, true);
            app.MagContactsBox.Text = 'Magnesium Contacts';
            app.MagContactsBox.Value = true;
            app.ProteinInteractionBox = uicheckbox(app.uipanel3);
            app.ProteinInteractionBox.ValueChangedFcn = createCallbackFcn(app, @ProteinInteractionBox_Callback, true);
            app.ProteinInteractionBox.Text = 'Protein Interactions';
            app.ProteinInteractionBox.Value = true;
            app.FR3D_InteractionsBox = uicheckbox(app.uipanel3);
            app.FR3D_InteractionsBox.ValueChangedFcn = createCallbackFcn(app, @FR3D_InteractionsBox_Callback, true);
            app.FR3D_InteractionsBox.Text = 'FR3D interactions';
            app.FR3D_InteractionsBox.Value = true;
            app.MagInteractionBox = uicheckbox(app.uipanel3);
            app.MagInteractionBox.ValueChangedFcn = createCallbackFcn(app, @MagInteractionBox_Callback, true);
            app.MagInteractionBox.Text = 'Magnesium Interactions';
            app.MagInteractionBox.Value = true;
            app.text16 = uilabel(app.uipanel3);
            app.text16.Text = 'Custom Only';
            app.CoVarEntropyBox = uicheckbox(app.uipanel3);
            app.CoVarEntropyBox.ValueChangedFcn = createCallbackFcn(app, @CoVarEntropyBox_Callback, true);
            app.CoVarEntropyBox.Text = 'PASE Entropy';
            app.CoVarEntropyBox.Value = true;
            app.CalculateBtn = uibutton(app.figure1, 'push');
            app.CalculateBtn.ButtonPushedFcn = createCallbackFcn(app, @CalculateBtn_Callback, true);
            app.CalculateBtn.Text = 'Calculate';
            app.SaveBtn = uibutton(app.figure1, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtn_Callback, true);
            app.SaveBtn.Text = 'Save Excel';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Check Molecule Names';
            app.MoleculeNamesTable = uitable(app.uipanel4);
            app.MoleculeNamesTable.ColumnName = {'Full Name'; 'MoleculeGroup'; 'Molecule Type'; 'Molecule Name'; 'ChainID'; ''};
            app.MoleculeNamesTable.ColumnEditable = [false true true true false false];
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Settings';
            app.text1 = uilabel(app.figure1);
            app.text1.Text = 'DataSet Name';
            app.DataSetName = uieditfield(app.figure1, 'text');
            app.DataSetName.Value = 'DataSet Name';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RiboLabRV2_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @RiboLabRV2_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end